classdef CageLab < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		CageLabUIFigure                 matlab.ui.Figure
		TabGroup                        matlab.ui.container.TabGroup
		GeneralTab                      matlab.ui.container.Tab
		GridLayout                      matlab.ui.container.GridLayout
		GridLayout3                     matlab.ui.container.GridLayout
		Log                             matlab.ui.control.TextArea
		LogTextAreaLabel                matlab.ui.control.Label
		Image2                          matlab.ui.control.Image
		Image                           matlab.ui.control.Image
		GlobalSettingsPanel             matlab.ui.container.Panel
		GridLayout2                     matlab.ui.container.GridLayout
		AudioVolumeEditField            matlab.ui.control.NumericEditField
		AudioVolumeEditFieldLabel       matlab.ui.control.Label
		folderEditField                 matlab.ui.control.EditField
		LButton                         matlab.ui.control.Button
		UseALYXSwitch                   matlab.ui.control.Switch
		UseALYXSwitchLabel              matlab.ui.control.Label
		AudioSwitch                     matlab.ui.control.Switch
		AudioSwitchLabel                matlab.ui.control.Label
		DebugModeSwitch                 matlab.ui.control.Switch
		DebugModeSwitchLabel            matlab.ui.control.Label
		SmartBackgroundSwitch           matlab.ui.control.Switch
		SmartBackgroundSwitchLabel      matlab.ui.control.Label
		CageLabRemoteControlPanel       matlab.ui.container.Panel
		GridLayout4                     matlab.ui.container.GridLayout
		TestConnectionButton            matlab.ui.control.Button
		ConnectionModeEditField         matlab.ui.control.EditField
		ConnectionModeEditFieldLabel    matlab.ui.control.Label
		RemoteServerPortEditField       matlab.ui.control.EditField
		RemoteServerPortEditFieldLabel  matlab.ui.control.Label
		RemoteServerIPEditField         matlab.ui.control.EditField
		RemoteServerIPEditFieldLabel    matlab.ui.control.Label
		BackgroundColorEditField        matlab.ui.control.EditField
		BackgroundColorEditFieldLabel   matlab.ui.control.Label
		PixelDensitypxcmEditField       matlab.ui.control.NumericEditField
		PixelDensitypxcmEditFieldLabel  matlab.ui.control.Label
		DistancecmEditField             matlab.ui.control.NumericEditField
		DistancecmEditFieldLabel        matlab.ui.control.Label
		ScreenEditField                 matlab.ui.control.NumericEditField
		ScreenEditFieldLabel            matlab.ui.control.Label
		ALYXTab                         matlab.ui.container.Tab
		ALYXInterfacePanel              matlab.ui.container.Panel
		AlyxLayout                      matlab.ui.container.GridLayout
		QueryAlyxDatabaseLabel          matlab.ui.control.Label
		text4554_6                      matlab.ui.control.Label
		OKLabLocation                   matlab.ui.control.EditField
		text209_2                       matlab.ui.control.Label
		OKLabName                       matlab.ui.control.EditField
		text4554_5                      matlab.ui.control.Label
		OKResearcher                    matlab.ui.control.EditField
		text209                         matlab.ui.control.Label
		OKSubject                       matlab.ui.control.EditField
		AlyxProcedureLabel              matlab.ui.control.Label
		EditField_2                     matlab.ui.control.EditField
		EditField                       matlab.ui.control.EditField
		OKAlyxTaskProtocol              matlab.ui.control.EditField
		TaskProtocolEditFieldLabel      matlab.ui.control.Label
		text4554_4                      matlab.ui.control.Label
		OKAlyxBrainRegion               matlab.ui.control.DropDown
		text4554_3                      matlab.ui.control.Label
		OKAlyxProject                   matlab.ui.control.DropDown
		OKAlyxProcedure                 matlab.ui.control.DropDown
		OKAlyxRequest                   matlab.ui.control.EditField
		OKAlyxLOGINButton               matlab.ui.control.Button
		OKAlyxLOGOUTButton              matlab.ui.control.Button
		OKAlyxRequestButton             matlab.ui.control.Button
		OKAlyxResults                   matlab.ui.control.Table
		text4554_2                      matlab.ui.control.Label
		text4554                        matlab.ui.control.Label
		TouchTrainingTab                matlab.ui.container.Tab
		TouchTrainingTaskPanel          matlab.ui.container.Panel
		GridLayout5                     matlab.ui.container.GridLayout
		ColorEditField                  matlab.ui.control.EditField
		ColorEditFieldLabel             matlab.ui.control.Label
		STARTButton                     matlab.ui.control.Button
		StimulusTypeDropDown            matlab.ui.control.DropDown
		StimulusTypeDropDownLabel       matlab.ui.control.Label
		DifficultyDropDown              matlab.ui.control.DropDown
		DifficultyDropDownLabel         matlab.ui.control.Label
		MinimumSizeEditField            matlab.ui.control.NumericEditField
		MinimumSizeEditFieldLabel       matlab.ui.control.Label
		MaximumSizeEditField            matlab.ui.control.NumericEditField
		MaximumSizeEditFieldLabel       matlab.ui.control.Label
		RandomRewardEditField           matlab.ui.control.NumericEditField
		RandomRewardEditFieldLabel      matlab.ui.control.Label
		TimeoutEditField                matlab.ui.control.NumericEditField
		TimeoutEditFieldLabel           matlab.ui.control.Label
		Phase120EditField               matlab.ui.control.NumericEditField
		Phase120EditFieldLabel          matlab.ui.control.Label
		MatchtoSampleTab                matlab.ui.container.Tab
		DelayedMTSTab                   matlab.ui.container.Tab
		CategorisationTab               matlab.ui.container.Tab
		ICATab                          matlab.ui.container.Tab
		PatchForagingTab                matlab.ui.container.Tab
	end


	properties (Access = private)
		opts = []% options
		zmq % zeromq connection class
	end

	methods (Access = private)

		function opts = generalOptions(app)
			opts.screen = app.ScreenEditField.Value;
			opts.distance = app.DistancecmEditField.Value;
			opts.density = app.PixelDensitypxcmEditField.Value;
			opts.bg = eval(app.BackgroundColorEditField.Value);
			opts.smartbg = app.SmartBackgroundSwitch.Value;
			opts.audio = app.AudioSwitch.Value;
			opts.alyx = app.UseALYXSwitch.Value;
			opts.folder = app.folderEditField.Value;
			opts.debug = app.DebugModeSwitch.Value;
		end
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Button pushed function: STARTButton
		function STARTButtonPushed(app, event)
			opts = generalOptions(app);
			opts.fg = app.ColorEditField.Value;
			opts.timeout = app.TimeoutEditField.Value;
			opts.maxSize = app.MaximumSizeEditField.Value;
			opts.minSize = app.MinimumSizeEditField.Value;
			opts.task = app.DifficultyDropDown.Value;
			opts.stimulus = app.StimulusTypeDropDown.Value;
			opts.randomReward = app.RandomRewardEditField.Value;
			opts.phase = app.Phase120EditField.Value;
			app.opts = opts;

			startTouchTraining(opts);
		end

		% Button pushed function: TestConnectionButton
		function TestConnectionButtonPushed(app, event)
			ip = app.RemoteServerIPEditField.Value;
			port = app.RemoteServerPortEditField.Value;
			protocol = app.ConnectionModeEditField.Value;
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			% Get the file path for locating images
			pathToMLAPP = fileparts(mfilename('fullpath'));
			app.CageLabUIFigure = uifigure('Visible', 'off');
			app.CageLabUIFigure.Name = 'CageLab';
			app.TabGroup = uitabgroup(app.CageLabUIFigure);
			app.GeneralTab = uitab(app.TabGroup);
			app.GeneralTab.Title = '💫 General ';
			app.GridLayout = uigridlayout(app.GeneralTab);
			app.GlobalSettingsPanel = uipanel(app.GridLayout);
			app.GlobalSettingsPanel.Title = 'Global Settings';
			app.GridLayout2 = uigridlayout(app.GlobalSettingsPanel);
			app.ScreenEditFieldLabel = uilabel(app.GridLayout2);
			app.ScreenEditFieldLabel.Text = 'Screen';
			app.ScreenEditField = uieditfield(app.GridLayout2, 'numeric');
			app.DistancecmEditFieldLabel = uilabel(app.GridLayout2);
			app.DistancecmEditFieldLabel.Text = 'Distance (cm)';
			app.DistancecmEditField = uieditfield(app.GridLayout2, 'numeric');
			app.DistancecmEditField.Limits = [1 200];
			app.DistancecmEditField.Value = 40;
			app.PixelDensitypxcmEditFieldLabel = uilabel(app.GridLayout2);
			app.PixelDensitypxcmEditFieldLabel.Text = 'Pixel Density (px/cm)';
			app.PixelDensitypxcmEditField = uieditfield(app.GridLayout2, 'numeric');
			app.PixelDensitypxcmEditField.Value = 36;
			app.BackgroundColorEditFieldLabel = uilabel(app.GridLayout2);
			app.BackgroundColorEditFieldLabel.Text = 'Background Color';
			app.BackgroundColorEditField = uieditfield(app.GridLayout2, 'text');
			app.BackgroundColorEditField.Value = '0.5 0.5 0.5';
			app.CageLabRemoteControlPanel = uipanel(app.GridLayout2);
			app.CageLabRemoteControlPanel.Title = 'CageLab Remote Control';
			app.GridLayout4 = uigridlayout(app.CageLabRemoteControlPanel);
			app.RemoteServerIPEditFieldLabel = uilabel(app.GridLayout4);
			app.RemoteServerIPEditFieldLabel.Text = 'Remote Server IP ';
			app.RemoteServerIPEditField = uieditfield(app.GridLayout4, 'text');
			app.RemoteServerIPEditField.Value = '172.25.25.10';
			app.RemoteServerPortEditFieldLabel = uilabel(app.GridLayout4);
			app.RemoteServerPortEditFieldLabel.Text = 'Remote Server Port';
			app.RemoteServerPortEditField = uieditfield(app.GridLayout4, 'text');
			app.RemoteServerPortEditField.Value = '6666';
			app.ConnectionModeEditFieldLabel = uilabel(app.GridLayout4);
			app.ConnectionModeEditFieldLabel.Text = 'Connection Mode';
			app.ConnectionModeEditField = uieditfield(app.GridLayout4, 'text');
			app.ConnectionModeEditField.Value = 'HTTP';
			app.TestConnectionButton = uibutton(app.GridLayout4, 'push');
			app.TestConnectionButton.ButtonPushedFcn = createCallbackFcn(app, @TestConnectionButtonPushed, true);
			app.TestConnectionButton.Icon = fullfile(pathToMLAPP, 'images', 'link.circle.svg');
			app.TestConnectionButton.Text = 'Test Connection';
			app.SmartBackgroundSwitchLabel = uilabel(app.GridLayout2);
			app.SmartBackgroundSwitchLabel.Text = 'Smart Background';
			app.SmartBackgroundSwitch = uiswitch(app.GridLayout2, 'slider');
			app.SmartBackgroundSwitch.Value = 'On';
			app.DebugModeSwitchLabel = uilabel(app.GridLayout2);
			app.DebugModeSwitchLabel.Text = 'Debug Mode';
			app.DebugModeSwitch = uiswitch(app.GridLayout2, 'slider');
			app.AudioSwitchLabel = uilabel(app.GridLayout2);
			app.AudioSwitchLabel.Text = 'Audio?';
			app.AudioSwitch = uiswitch(app.GridLayout2, 'slider');
			app.AudioSwitch.Value = 'On';
			app.UseALYXSwitchLabel = uilabel(app.GridLayout2);
			app.UseALYXSwitchLabel.Text = 'Use ALYX';
			app.UseALYXSwitch = uiswitch(app.GridLayout2, 'slider');
			app.UseALYXSwitch.Value = 'On';
			app.LButton = uibutton(app.GridLayout2, 'push');
			app.LButton.Text = 'L';
			app.folderEditField = uieditfield(app.GridLayout2, 'text');
			app.folderEditField.Value = '/images';
			app.AudioVolumeEditFieldLabel = uilabel(app.GridLayout2);
			app.AudioVolumeEditFieldLabel.Text = 'Audio Volume';
			app.AudioVolumeEditField = uieditfield(app.GridLayout2, 'numeric');
			app.AudioVolumeEditField.Limits = [0 1];
			app.AudioVolumeEditField.Value = 1;
			app.GridLayout3 = uigridlayout(app.GridLayout);
			app.Image = uiimage(app.GridLayout3);
			app.Image.ImageSource = fullfile(pathToMLAPP, 'images', 'cebsit.png');
			app.Image2 = uiimage(app.GridLayout3);
			app.Image2.ImageSource = fullfile(pathToMLAPP, 'Logo.png');
			app.LogTextAreaLabel = uilabel(app.GridLayout3);
			app.LogTextAreaLabel.Text = 'Log';
			app.Log = uitextarea(app.GridLayout3);
			app.ALYXTab = uitab(app.TabGroup);
			app.ALYXTab.Title = '🗃️ ALYX';
			app.ALYXInterfacePanel = uipanel(app.ALYXTab);
			app.ALYXInterfacePanel.Title = 'ALYX Interface';
			app.AlyxLayout = uigridlayout(app.ALYXInterfacePanel);
			app.text4554 = uilabel(app.AlyxLayout);
			app.text4554.Text = 'ALYX IP & Port';
			app.text4554_2 = uilabel(app.AlyxLayout);
			app.text4554_2.Text = 'ALYX User';
			app.OKAlyxResults = uitable(app.AlyxLayout);
			app.OKAlyxResults.ColumnName = {'ID'; 'Start'; 'End'; 'Lab'};
			app.OKAlyxResults.RowName = {};
			app.OKAlyxRequestButton = uibutton(app.AlyxLayout, 'push');
			app.OKAlyxRequestButton.Icon = fullfile(pathToMLAPP, 'images', 'send.svg');
			app.OKAlyxRequestButton.Enable = 'off';
			app.OKAlyxRequestButton.Text = 'Request';
			app.OKAlyxLOGOUTButton = uibutton(app.AlyxLayout, 'push');
			app.OKAlyxLOGOUTButton.Icon = fullfile(pathToMLAPP, 'images', 'link.circle.off.svg');
			app.OKAlyxLOGOUTButton.Text = 'Log out';
			app.OKAlyxLOGINButton = uibutton(app.AlyxLayout, 'push');
			app.OKAlyxLOGINButton.Icon = fullfile(pathToMLAPP, 'images', 'link.circle.svg');
			app.OKAlyxLOGINButton.Text = 'Login';
			app.OKAlyxRequest = uieditfield(app.AlyxLayout, 'text');
			app.OKAlyxRequest.Enable = 'off';
			app.OKAlyxRequest.Value = 'sessions?subject=test02';
			app.OKAlyxProcedure = uidropdown(app.AlyxLayout);
			app.OKAlyxProcedure.Items = {'Behavior training/tasks', 'Imaging', 'Intraocular injection', 'Optical stimulation'};
			app.OKAlyxProcedure.Value = 'Behavior training/tasks';
			app.OKAlyxProject = uidropdown(app.AlyxLayout);
			app.OKAlyxProject.Items = {'Default', 'Visual testing', 'Touch Task', 'Saccade Task'};
			app.OKAlyxProject.Value = 'Default';
			app.text4554_3 = uilabel(app.AlyxLayout);
			app.text4554_3.Text = 'Alyx Project';
			app.OKAlyxBrainRegion = uidropdown(app.AlyxLayout);
			app.OKAlyxBrainRegion.Items = {'Cerebral cortex', 'Thalamus', 'Hippocampus', 'Basal Ganglia'};
			app.OKAlyxBrainRegion.Value = 'Cerebral cortex';
			app.text4554_4 = uilabel(app.AlyxLayout);
			app.text4554_4.Text = 'Brain Region';
			app.TaskProtocolEditFieldLabel = uilabel(app.AlyxLayout);
			app.TaskProtocolEditFieldLabel.Text = 'Task Protocol';
			app.OKAlyxTaskProtocol = uieditfield(app.AlyxLayout, 'text');
			app.EditField = uieditfield(app.AlyxLayout, 'text');
			app.EditField.Value = 'http://172.16.102.30:8000';
			app.EditField_2 = uieditfield(app.AlyxLayout, 'text');
			app.EditField_2.Value = 'admin';
			app.AlyxProcedureLabel = uilabel(app.AlyxLayout);
			app.AlyxProcedureLabel.Text = 'Alyx Procedure';
			app.OKSubject = uieditfield(app.AlyxLayout, 'text');
			app.OKSubject.Tag = 'OKTrainingName';
			app.OKSubject.Tooltip = {'Unique Subject Name / ID'};
			app.OKSubject.Value = 'Simulcra';
			app.text209 = uilabel(app.AlyxLayout);
			app.text209.Tag = 'text209';
			app.text209.Text = 'Subject ID🧠';
			app.OKResearcher = uieditfield(app.AlyxLayout, 'text');
			app.OKResearcher.Tag = 'OKTrainingResearcherName';
			app.OKResearcher.Tooltip = {'Experimenter name'};
			app.OKResearcher.Value = 'Jane Doe';
			app.text4554_5 = uilabel(app.AlyxLayout);
			app.text4554_5.Tag = 'text4554';
			app.text4554_5.Text = 'Lab Member🧑🏻‍🔬';
			app.OKLabName = uieditfield(app.AlyxLayout, 'text');
			app.OKLabName.Tag = 'OKTrainingName';
			app.OKLabName.Tooltip = {'Lab / Group name'};
			app.OKLabName.Value = 'Lab';
			app.text209_2 = uilabel(app.AlyxLayout);
			app.text209_2.Tag = 'text209';
			app.text209_2.Text = 'Lab Name🥼';
			app.OKLabLocation = uieditfield(app.AlyxLayout, 'text');
			app.OKLabLocation.Tag = 'OKTrainingResearcherName';
			app.OKLabLocation.Tooltip = {'Room / building where experiment is being performed'};
			app.OKLabLocation.Value = 'Room 10';
			app.text4554_6 = uilabel(app.AlyxLayout);
			app.text4554_6.Tag = 'text4554';
			app.text4554_6.Text = 'Lab Location🗺️';
			app.QueryAlyxDatabaseLabel = uilabel(app.AlyxLayout);
			app.QueryAlyxDatabaseLabel.Text = 'Query Alyx Database:';
			app.TouchTrainingTab = uitab(app.TabGroup);
			app.TouchTrainingTab.Title = '👆🏻 Touch Training';
			app.TouchTrainingTaskPanel = uipanel(app.TouchTrainingTab);
			app.TouchTrainingTaskPanel.Title = 'Touch Training Task';
			app.GridLayout5 = uigridlayout(app.TouchTrainingTaskPanel);
			app.Phase120EditFieldLabel = uilabel(app.GridLayout5);
			app.Phase120EditFieldLabel.Text = 'Phase (1-20)';
			app.Phase120EditField = uieditfield(app.GridLayout5, 'numeric');
			app.Phase120EditField.Limits = [1 20];
			app.Phase120EditField.Value = 1;
			app.TimeoutEditFieldLabel = uilabel(app.GridLayout5);
			app.TimeoutEditFieldLabel.Text = 'Timeout';
			app.TimeoutEditField = uieditfield(app.GridLayout5, 'numeric');
			app.TimeoutEditField.Value = 4;
			app.RandomRewardEditFieldLabel = uilabel(app.GridLayout5);
			app.RandomRewardEditFieldLabel.Text = 'Random Reward';
			app.RandomRewardEditField = uieditfield(app.GridLayout5, 'numeric');
			app.RandomRewardEditField.Value = 10;
			app.MaximumSizeEditFieldLabel = uilabel(app.GridLayout5);
			app.MaximumSizeEditFieldLabel.Text = 'Maximum Size';
			app.MaximumSizeEditField = uieditfield(app.GridLayout5, 'numeric');
			app.MaximumSizeEditField.Value = 34;
			app.MinimumSizeEditFieldLabel = uilabel(app.GridLayout5);
			app.MinimumSizeEditFieldLabel.Text = 'Minimum Size';
			app.MinimumSizeEditField = uieditfield(app.GridLayout5, 'numeric');
			app.MinimumSizeEditField.Value = 1;
			app.DifficultyDropDownLabel = uilabel(app.GridLayout5);
			app.DifficultyDropDownLabel.Text = 'Difficulty';
			app.DifficultyDropDown = uidropdown(app.GridLayout5);
			app.DifficultyDropDown.Items = {'Simple', 'Normal'};
			app.DifficultyDropDown.Value = 'Normal';
			app.StimulusTypeDropDownLabel = uilabel(app.GridLayout5);
			app.StimulusTypeDropDownLabel.Text = 'Stimulus Type';
			app.StimulusTypeDropDown = uidropdown(app.GridLayout5);
			app.StimulusTypeDropDown.Items = {'Picture', 'Disc'};
			app.StimulusTypeDropDown.Value = 'Picture';
			app.STARTButton = uibutton(app.GridLayout5, 'push');
			app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
			app.STARTButton.Tag = 'touchTraining';
			app.STARTButton.Icon = fullfile(pathToMLAPP, 'images', 'send.svg');
			app.STARTButton.Text = 'START';
			app.ColorEditFieldLabel = uilabel(app.GridLayout5);
			app.ColorEditFieldLabel.Text = 'Color';
			app.ColorEditField = uieditfield(app.GridLayout5, 'text');
			app.ColorEditField.Value = '1 0.9 0.3';
			app.MatchtoSampleTab = uitab(app.TabGroup);
			app.MatchtoSampleTab.Title = '🔍 Match to Sample';
			app.DelayedMTSTab = uitab(app.TabGroup);
			app.DelayedMTSTab.Title = '⏸️🔍 Delayed MTS';
			app.CategorisationTab = uitab(app.TabGroup);
			app.CategorisationTab.Title = '🐃 Categorisation';
			app.ICATab = uitab(app.TabGroup);
			app.ICATab.Title = '🐄 ICA';
			app.PatchForagingTab = uitab(app.TabGroup);
			app.PatchForagingTab.Title = '🎋 Patch Foraging';
			% Show the figure after all components are created
			app.CageLabUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = CageLab

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.CageLabUIFigure)
			else

				% Focus the running singleton app
				figure(runningApp.CageLabUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.CageLabUIFigure)
		end
	end
end